classdef Knob < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        PlotButton          matlab.ui.control.Button
        Lamp                matlab.ui.control.Lamp
        UIAxes              matlab.ui.control.UIAxes
        X0EditFieldLabel    matlab.ui.control.Label
        X0EditField         matlab.ui.control.NumericEditField
        XendEditFieldLabel  matlab.ui.control.Label
        XendEditField       matlab.ui.control.NumericEditField
        LinewidthKnobLabel  matlab.ui.control.Label
        LinewidthKnob       matlab.ui.control.Knob
        ColorKnobLabel      matlab.ui.control.Label
        ColorKnob           matlab.ui.control.Knob
    end

    
    properties (Access = private)
        LW  %Linewidth
        Col %Color
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, a)
            C=jet(100);
            app.LW = 1;
            app.Col = C(1,:);
            app.Lamp.Color=C(1,:);
            X0=app.X0EditField.Value;
            Xend=app.XendEditField.Value;
            x=linspace(X0,Xend,200);
            y=sin(x);
            plot(x,y,'linewidth',app.LW,'color',app.Col,'parent',app.UIAxes);
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            X0=app.X0EditField.Value;
            Xend=app.XendEditField.Value;
            x=linspace(X0,Xend,200);
            y=sin(x);
            plot(x,y,'linewidth',app.LW,'color',app.Col,'parent',app.UIAxes);
        end

        % Value changing function: LinewidthKnob
        function LinewidthKnobValueChanging(app, event)
            app.LW = event.Value;
            X0=app.X0EditField.Value;
            Xend=app.XendEditField.Value;
            x=linspace(X0,Xend,200);
            y=sin(x);
            plot(x,y,'linewidth',app.LW,'color',app.Col,'parent',app.UIAxes);
        end

        % Value changing function: ColorKnob
        function ColorKnobValueChanging(app, event)
            
            R =round( event.Value);
            C=jet(100);
            app.Col=C(R,:);
            app.Lamp.Color=C(R,:);
            X0=app.X0EditField.Value;
            Xend=app.XendEditField.Value;
            x=linspace(X0,Xend,200);
            y=sin(x);
            plot(x,y,'linewidth',app.LW,'color',app.Col,'parent',app.UIAxes);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.Lamp = uilamp(app.UIFigure);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Diagram')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.X0EditFieldLabel = uilabel(app.UIFigure);
            app.X0EditFieldLabel.Text = 'X0';
            app.X0EditField = uieditfield(app.UIFigure, 'numeric');
            app.XendEditFieldLabel = uilabel(app.UIFigure);
            app.XendEditFieldLabel.Text = 'Xend';
            app.XendEditField = uieditfield(app.UIFigure, 'numeric');
            app.XendEditField.Value = 10;
            app.LinewidthKnobLabel = uilabel(app.UIFigure);
            app.LinewidthKnobLabel.Text = 'Linewidth';
            app.LinewidthKnob = uiknob(app.UIFigure, 'continuous');
            app.LinewidthKnob.Limits = [1 11];
            app.LinewidthKnob.ValueChangingFcn = createCallbackFcn(app, @LinewidthKnobValueChanging, true);
            app.LinewidthKnob.Value = 1;
            app.ColorKnobLabel = uilabel(app.UIFigure);
            app.ColorKnobLabel.Text = 'Color';
            app.ColorKnob = uiknob(app.UIFigure, 'continuous');
            app.ColorKnob.Limits = [1 100];
            app.ColorKnob.ValueChangingFcn = createCallbackFcn(app, @ColorKnobValueChanging, true);
            app.ColorKnob.Value = 1;
        end
    end

    methods (Access = public)

        % Construct app
        function app = Knob(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end